classdef GEOAppS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GEOAppSLabel  matlab.ui.control.Label
        GeomorphologyExperimentsandObservationsApplicationSuiteLabel  matlab.ui.control.Label
        UIAxes        matlab.ui.control.UIAxes
        InfoButton    matlab.ui.control.Button
        OpenButton    matlab.ui.control.Button
    end

    % Properties for app components instantiated after apps are registerd
    properties (Access = public)
        AppsTabGroup  matlab.ui.container.TabGroup
    end
    
    properties (Access = private)
        currentAppTitle = ''
        allApps = {}
    end

    methods (Access = private)
    
        function registerSubApp(app, sub_app)
           app.allApps{end+1} = sub_app;
        end
        
        function sub_app = makeApp(app, app_struct)
            name = app_struct.Name.Text;
            title = app_struct.Title.Text;
            description = app_struct.Description.Text;
            script = app_struct.Script.Text;
            
            authors = {};
            author = struct2cell(app_struct.Author)
            for i = (1:length(app_struct.Author))
                authors{end+1} = author{i};
            end
            
            imSrc = 'no_img.png';
            if isfield(app_struct, 'ImgSrc')
                imSrc = app_struct.ImgSrc.Text;
            end
            
            link = '';
            if isfield(app_struct, 'Link')
                link = appStruct.Link.Text;
            end
            
            sub_app = app_class(title, name, description, authors, script, imSrc, link);
        end
        
        % Create tab and tab group components
        function draw_tabs(app)
            % Create TabGroup
            app.AppsTabGroup = uitabgroup(app.UIFigure);
            app.AppsTabGroup.SelectionChangedFcn = createCallbackFcn(app, @SetCurrentApp, true);
            app.AppsTabGroup.Position = [51 90 540 271];            
            
            % Create tab components for each tab
            for i = (1:length(app.allApps))
                sub_app = app.allApps{i};
                tab_string = [sub_app.Name, 'Tab'];
                eval([tab_string, ' = uitab(app.AppsTabGroup)']);
                eval([tab_string, '.Title = sub_app.Title']);
                eval(['app.add_tab_content(', tab_string, ', sub_app)']);
            end
        end
        
        % create
        function draw_no_tabs(app)
            % Disable all buttons
            app.OpenButton.Enable = 'off';
            
            % Create TextArea
            textArea = uitextarea(app.UIFigure);
            textArea.Editable = 'off';
            textArea.FontSize = 18;
            textArea.BackgroundColor = [0.902 0.902 0.902];
            textArea.Position = [51 90 540 271];
            textArea.Value = {'Uh oh, looks like there are no apps to choose from! To register viewable apps, edit the `config.xml` file.'};
        end
        
        function add_tab_content(app, tab, sub_app)
            % Create TitleTextAreaLabel
            titleTextAreaLabel = uilabel(tab);
            titleTextAreaLabel.HorizontalAlignment = 'right';
            titleTextAreaLabel.Position = [20 200 60 15];
            titleTextAreaLabel.Text = 'Title:';
            
            % Create TitleTextArea
            titleTextArea = uitextarea(tab);
            titleTextArea.Position = [90 190 170 30];
            titleTextArea.Value = {sub_app.Title};
            titleTextArea.Editable = 'off';

            % Create AuthorsTextAreaLabel
            authorsTextAreaLabel = uilabel(tab);
            authorsTextAreaLabel.HorizontalAlignment = 'right';
            authorsTextAreaLabel.Position = [20 155 60 15];
            authorsTextAreaLabel.Text = 'Author(s):';

            
            % Create AuthorsTextArea
            authorsTextArea = uitextarea(tab);
            authorsTextArea.Position = [90 135 170 40];
            authorsTextArea.Value = sub_app.Authors;
            authorsTextArea.Editable = 'off';

            % Create DescriptionTextAreaLabel
            descriptionTextAreaLabel = uilabel(tab);
            descriptionTextAreaLabel.HorizontalAlignment = 'right';
            descriptionTextAreaLabel.Position = [10 95 70 15];
            descriptionTextAreaLabel.Text = 'Description:';

            % Create DescriptionTextArea
            descriptionTextArea = uitextarea(tab);
            descriptionTextArea.Position = [90 35 170 85];
            descriptionTextArea.Value = {sub_app.Description};
            descriptionTextArea.Editable = 'off';
            
            % Create UIAxes2
            imAxes = uiaxes(tab);
            imAxes.Position = [260 35 260 175];
            imshow(sub_app.ImgSrc, 'Parent', imAxes, 'XData', [.1 .9], 'YData', [.1 .9]); 
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('utils'), genpath('resources'), genpath('apps'));
            % register apps from config file
            app_config = xml2struct('config.xml');
            if isfield(app_config.applist, 'app')
                app_list = app_config.applist.app;
                for i = (1:length(app_list))
                    app_cell = app_list(i);
                    sub_app = app.makeApp(app_cell{1});
                    app.registerSubApp(sub_app);                
                end
            end
            
            % initalize window content
            app.InfoButton.Enable = 'off';
            if ~isempty(app.allApps)
                app.draw_tabs();
                app.currentAppTitle = app.allApps{1}.Title;
            else
                app.draw_no_tabs();
            end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            % get current app and open script
            for i = (1:length(app.allApps))
                if strcmpi(app.allApps{i}.Title, app.currentAppTitle)
                    eval(app.allApps{i}.Script);
                    break
                end
            end
        end

        % Callback function
        function SetCurrentApp(app, event)
            selectedTab = app.AppsTabGroup.SelectedTab;
            app.currentAppTitle = selectedTab.Title;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GEOAppSLabel = uilabel(app.UIFigure);
            app.GEOAppSLabel.Text = 'GEOAppS';
            app.GeomorphologyExperimentsandObservationsApplicationSuiteLabel = uilabel(app.UIFigure);
            app.GeomorphologyExperimentsandObservationsApplicationSuiteLabel.Text = 'Geomorphology Experiments and Observations Application Suite';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 0.5 1];
            app.UIAxes.YTick = [0 0.5 1];
            app.UIAxes.ZTick = [0 0.5 1];
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.Text = 'Info';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GEOAppS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end