classdef BeachProfiles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ControlsPanel            matlab.ui.container.Panel
        StopButton               matlab.ui.control.Button
        RunButton                matlab.ui.control.Button
        InitializeButton         matlab.ui.control.Button
        HelpButton               matlab.ui.control.Button
        SetConditionsPanel       matlab.ui.container.Panel
        grainsize1mmLabel        matlab.ui.control.Label
        size1                    matlab.ui.control.Spinner
        sea_level_up             matlab.ui.control.Button
        sea_level_down           matlab.ui.control.Button
        grainsize2mmLabel        matlab.ui.control.Label
        size2                    matlab.ui.control.Spinner
        grainsize3mmLabel        matlab.ui.control.Label
        size3                    matlab.ui.control.Spinner
        extremewaveheightmLabel  matlab.ui.control.Label
        wave_height              matlab.ui.control.Spinner
        duneheightmLabel         matlab.ui.control.Label
        dune_height              matlab.ui.control.Spinner
        sea_level_axes           matlab.ui.control.UIAxes
        MultipleprofilesLabel    matlab.ui.control.Label
        MultipleprofilesSwitch   matlab.ui.control.Switch
        SpeedKnobLabel           matlab.ui.control.Label
        SpeedKnob                matlab.ui.control.Knob
        BeachProfilesLabel       matlab.ui.control.Label
        profileAxes              matlab.ui.control.UIAxes
        timeAxes                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        x           % cross-shore positions (m)
        x_rel       % relative cross-shore positions
        z           % elevation (m)       
        z_ref
        sea_level   % m above starting sea level
        colors = [[1 0.8 0.2];[0.851 0.3294 0.102];[0.4 0.2 0.2]];
        labels = {'w/ Bruun''s Rule', 'w/out Bruun''s Rule'};

        sl_step = 0.25;
        
        % constants
        A_table;
        H_e         % extreme wave height
        h_c         % (m)
        h_dune      % (m)
        dune_dist
        hc_dist
        
        A           % z = Ax^(2/3)
        R
        
        shoreline   % position of shoreline
        shoreline_ref % position of reference shoreline (No Bruun's Rule)
        max_vals = 200;
        t
        
        stopped     % app running or stopped
        wait_time
        
        num_profiles
    end

    methods (Access = private)
    
        function updatePlot(app)            
            % update beach profile plot
            ax = app.profileAxes;
            cla(ax);
            p = [];
            hold(ax, 'on')
            for i = (1:1:app.num_profiles)
                p(end+1) = plot(ax, app.x, app.z(i, :), 'color', app.colors(i, :), 'linewidth', 2);
                plot(ax, app.x, app.z_ref(i, :), '--', 'color', app.colors(i, :), 'linewidth', 1.5);
            end
            
            % find shoreline; plot and fill ocean
            s = max(app.shoreline(:, end));
            %s = (app.shoreline(1, end));
            i = find(app.x < s, 1, 'last');
            if isempty(i)
                i = 0;
            elseif i == length(app.x)
                i = i-1;
            end
            
            max_z = app.z;
            if app.num_profiles > 1
                max_z = max(app.z);
            end
            plot(ax, [s app.x(end)], [app.sea_level app.sea_level], 'b', 'linewidth', 2);
            patch(ax, [app.x(i+1) app.x(end) fliplr(app.x(i+1:end))],...
                [app.sea_level app.sea_level fliplr(max_z(i+1:end))], ...
                [0 0.5 1], 'facealpha', 0.3);
            
            % fill land
            for i = (1:1:app.num_profiles)
            patch(ax, [app.x, app.x(end), app.x(1)],...
                [app.z(i, :), -app.h_c-2, -app.h_c-2], ...
                app.colors(i, :), 'facealpha', 0.1);
            end
            

            % plot shoreline position history
            ax = app.timeAxes;
            cla(ax);
            if length(app.shoreline(1,:)) > app.max_vals
                app.shoreline = app.shoreline(:, 2:end);
                app.shoreline_ref = app.shoreline_ref(:, 2:end);                
            end
            hold(ax, 'on');
            
            for i = (1:1:app.num_profiles)
                temp = app.shoreline(i, :);
                pad = padarray(temp, [0, app.max_vals-(length(temp))], nan, 'post');
                plot(ax, app.t, pad, '*', 'color', app.colors(i, :), 'linewidth', 2); 
                temp = app.shoreline_ref(i, :);
                pad = padarray(temp, [0, app.max_vals-(length(temp))], nan, 'post');
                plot(ax, app.t, pad, '-.', 'color', app.colors(i, :), 'linewidth', 2); 
            end
            
            legend(ax, app.labels, 'location', 'northeast', 'orientation', 'horizontal');
        end
        
        function updateSeaLevel(app, slr)
            app.sea_level = app.sea_level + slr;
            % update sl indicator
            bar(app.sea_level_axes, 0, app.sea_level, 3);
            
            % move shoreline
            app.getR();
            app.x_rel = app.x+(app.R);
            
            app.getCurrentProfile();
            
            % save shoreline positions
            if ~isempty(app.shoreline)
                app.shoreline_ref(:,end+1) = -(sign(app.sea_level)* (abs(app.sea_level./app.A)).^(3/2));
                app.shoreline(:, end+1) = - (app.x_rel(:, 1) -app.x(1));
            else
                app.shoreline = zeros(app.num_profiles, 1);
                app.shoreline_ref = app.shoreline;
            end
            
            app.updatePlot();
        end
        
        function getR(app)
            app.dune_dist = ((app.h_dune)./app.A).^(3/2);
            app.R = (app.sea_level * (app.dune_dist + app.hc_dist))/(app.h_c + app.h_dune);
        end
        
        function getCurrentProfile(app)
            for i = (1:1:app.num_profiles)
                app.z(i,:) = -app.A(i)*(sign(app.x_rel(i,:)).*abs(app.x_rel(i,:)).^(2/3)) + app.sea_level;
            end
            app.z(app.x_rel < 0) = min(app.z(app.x_rel < 0), app.z_ref(app.x_rel < 0));
            app.z(app.z < -app.h_c+app.sea_level) = -app.h_c + app.sea_level;            
        end
       
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % intialize
            app.A_table = [[0.063 0.0672 0.0714 0.0756 0.0798 0.084 0.0872 0.0904 0.0936 0.0968];...
                            [0.100 0.103 0.106 0.109 0.112 0.115 0.117 0.119 0.121 0.123];...
                            [0.125 0.127 0.129 0.131 0.133 0.135 0.137 0.139 0.141 0.143];...
                            [0.145 0.1466 0.1482 0.1498 0.1514 0.153 0.1546 0.1562 0.1578 0.1594];...
                            [0.161 0.1622 0.1634 0.1646 0.1658 0.167 0.1682 0.1694 0.1706 0.1718];...
                            [0.173 0.1742 0.1754 0.1766 0.1778 0.179 0.1802 0.1814 0.1826 0.1838];...
                            [0.185 0.1859 0.1868 0.1877 0.1886 0.1895 0.1904 0.1913 0.1922 0.1931];...
                            [0.194 0.1948 0.1956 0.1964 0.1972 0.198 0.1988 0.1996 0.2004 0.2012];...
                            [0.202 0.2028 0.2036 0.2044 0.2052 0.206 0.2068 0.2076 0.2084 0.2092];...
                            [0.210 0.2108 0.2116 0.2124 0.2132 0.2140 0.2148 0.2156 0.2164 0.2172]];
            app.InitializeButtonPushed();
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.RunButton.Enable = 'off';
            app.InitializeButton.Enable = 'off';
            app.StopButton.Enable = 'on';
            app.sea_level_up.Enable = 'off';
            app.sea_level_down.Enable = 'off';
            app.MultipleprofilesSwitch.Enable = 'off';
            app.stopped = 0;
            app.size1.Enable = 'off';
            app.size2.Enable = 'off';
            app.size3.Enable = 'off';
            app.wave_height.Enable = 'off';
            app.dune_height.Enable = 'off';
            
            dir = 1;
            while ~app.stopped
                app.updateSeaLevel(dir*app.sl_step);
                app.updatePlot();
                if app.sea_level <= 0 || app.sea_level >= app.h_dune
                    dir = -1*dir;
                end
                drawnow
                pause(app.wait_time);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.RunButton.Enable = 'on';
            app.InitializeButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.sea_level_up.Enable = 'on';
            app.sea_level_down.Enable = 'on';
            app.MultipleprofilesSwitch.Enable = 'on';
            app.stopped = 1;
            app.size1.Enable = 'on';
            if app.num_profiles > 1
                app.size2.Enable = 'on';
                app.size3.Enable = 'on';
            end
            app.wave_height.Enable = 'on';
            app.dune_height.Enable = 'on';
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            % set run state
            app.RunButton.Enable = 'on';
            app.InitializeButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.sea_level_up.Enable = 'on';
            app.sea_level_down.Enable = 'on';
            app.MultipleprofilesSwitch.Enable = 'on';
            app.stopped = 1;
            app.size1.Enable = 'on';
            app.size2.Enable = 'off';
            app.size3.Enable = 'off';
            app.wave_height.Enable = 'on';
            app.dune_height.Enable = 'on';
            app.SpeedKnobValueChanged();
            
            % initalize user inputs
            app.sea_level = 0;
            app.shoreline = [];
            switch app.MultipleprofilesSwitch.Value
                case 'On'
                    app.num_profiles = 3;
                    app.size2.Enable = 'on';
                    app.size3.Enable = 'on';
                otherwise
                    app.num_profiles = 1;
            end
            
            sizes = [app.size1.Value, app.size2.Value, app.size3.Value];
            
            % find a values
            app.A = nan(app.num_profiles, 1);
            for i = (1:1:app.num_profiles)
                d = sizes(i);
                row = floor((d*100)/10);
                col = floor(mod(d, 0.1)*100) + 1;
                app.A(i) = app.A_table(row, col);
            end
            
            % get dune height and depth of closure
            g = 9.81;
            T = 20;
            app.H_e = app.wave_height.Value;
            app.h_c = (2.28*app.H_e) - 68.5*((app.H_e^2)/(g * (T^2)));
            app.h_dune = app.dune_height.Value;
            
            % find distance to depth of closure
            app.hc_dist = (app.h_c./app.A).^(3/2);
           
            % limits of retreat
            max_retreat_sl = min(app.h_dune);
            max_retreat = (max_retreat_sl*max(app.hc_dist))/(app.h_dune - max_retreat_sl + app.h_c);
            
            % position vectors
            app.x = linspace(-max_retreat, max(app.hc_dist)+50, 150);
            app.z = nan(app.num_profiles, length(app.x));
            for i = (1:1:app.num_profiles)
                app.z(i,:) = -app.A(i)*(sign(app.x).*abs(app.x).^(2/3));
            end
            app.x_rel = repmat(app.x, app.num_profiles, 1);
            app.z_ref = app.z;
            app.z_ref(app.z_ref > app.h_dune) = app.h_dune;
            app.z_ref(app.z_ref < -app.h_c) = -app.h_c;
            
            % init sea level history
            app.t = (1:1:app.max_vals);
            
            % set up sea level axes
            ax = app.sea_level_axes;
            xticks(ax, []);
            xticklabels(ax, []);
            title(ax, 'Sea level (m)');
            grid(ax, 'on');
            ylim(ax, [0, app.h_dune]);
            
            % set up profile axes
            ax = app.profileAxes;
            xlabel(ax, 'cross-shore dist. (m)', 'fontsize', 16)
            xlim(ax, [app.x(1) app.x(end)]);
            ylabel(ax, 'elevation (m)', 'fontsize', 16);
            ylim(ax, [-app.h_c - 2, app.h_dune + 1]);
            box(ax, 'on');
            grid(ax, 'on');
            hold(ax, 'on');
            set(ax, 'fontsize', 16);
            
            % set up time axes
            ax = app.timeAxes;
            xticks(ax, []);
            ylim(ax, [-max_retreat, 0]);
            ylabel(ax, 'shoreline pos. (m)');
            xlim(ax, [app.t(1), app.t(end)]);
            
            app.updateSeaLevel(0);
        end

        % Button pushed function: sea_level_up
        function sea_level_upButtonPushed(app, event)
            app.sea_level_down.Enable = 'on';
            app.updateSeaLevel(app.sl_step);
            if app.sea_level >= app.h_dune
                app.sea_level_up.Enable = 'off';
            end
        end

        % Button pushed function: sea_level_down
        function sea_level_downButtonPushed(app, event)
            app.sea_level_up.Enable = 'on';
            app.updateSeaLevel(-app.sl_step);
            if app.sea_level <= 0
                app.sea_level_down.Enable = 'off';
            end
        end

        % Value changed function: SpeedKnob
        function SpeedKnobValueChanged(app, event)
            app.wait_time = 0.001*(10/app.SpeedKnob.Value);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            profilesHelpApp;
        end

        % Value changed function: MultipleprofilesSwitch
        function MultipleprofilesSwitchValueChanged(app, event)
            switch app.MultipleprofilesSwitch.Value
                case 'On'
                    app.num_profiles = 3;
                    app.size2.Enable = 'on';
                    app.size3.Enable = 'on';
                otherwise
                    app.num_profiles = 1;
                    app.size2.Enable = 'off';
                    app.size3.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.StopButton = uibutton(app.ControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RunButton = uibutton(app.ControlsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.InitializeButton = uibutton(app.ControlsPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.HelpButton = uibutton(app.ControlsPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Icon = 'help_icon.png';
            app.HelpButton.Text = 'Help';
            app.SetConditionsPanel = uipanel(app.UIFigure);
            app.SetConditionsPanel.Title = 'Set Conditions';
            app.grainsize1mmLabel = uilabel(app.SetConditionsPanel);
            app.grainsize1mmLabel.Text = 'grain size (1) (mm)';
            app.size1 = uispinner(app.SetConditionsPanel);
            app.size1.Step = 0.01;
            app.size1.Limits = [0 1.09];
            app.size1.Value = 0.5;
            app.sea_level_up = uibutton(app.SetConditionsPanel, 'push');
            app.sea_level_up.ButtonPushedFcn = createCallbackFcn(app, @sea_level_upButtonPushed, true);
            app.sea_level_up.Text = '+';
            app.sea_level_down = uibutton(app.SetConditionsPanel, 'push');
            app.sea_level_down.ButtonPushedFcn = createCallbackFcn(app, @sea_level_downButtonPushed, true);
            app.sea_level_down.Text = '-';
            app.grainsize2mmLabel = uilabel(app.SetConditionsPanel);
            app.grainsize2mmLabel.Text = 'grain size (2) (mm)';
            app.size2 = uispinner(app.SetConditionsPanel);
            app.size2.Step = 0.01;
            app.size2.Limits = [0 1.09];
            app.size2.Value = 0.6;
            app.grainsize3mmLabel = uilabel(app.SetConditionsPanel);
            app.grainsize3mmLabel.Text = 'grain size (3) (mm)';
            app.size3 = uispinner(app.SetConditionsPanel);
            app.size3.Step = 0.01;
            app.size3.Limits = [0 1.09];
            app.size3.Value = 0.7;
            app.extremewaveheightmLabel = uilabel(app.SetConditionsPanel);
            app.extremewaveheightmLabel.Text = 'extreme wave height (m)';
            app.wave_height = uispinner(app.SetConditionsPanel);
            app.wave_height.Limits = [1 8];
            app.wave_height.Value = 5;
            app.duneheightmLabel = uilabel(app.SetConditionsPanel);
            app.duneheightmLabel.Text = 'dune height (m)';
            app.dune_height = uispinner(app.SetConditionsPanel);
            app.dune_height.Limits = [0 10];
            app.dune_height.Value = 5;
            app.sea_level_axes = uiaxes(app.SetConditionsPanel);
            title(app.sea_level_axes, 'Sea level (m)')
            app.MultipleprofilesLabel = uilabel(app.SetConditionsPanel);
            app.MultipleprofilesLabel.Text = 'Multiple profiles:';
            app.MultipleprofilesSwitch = uiswitch(app.SetConditionsPanel, 'slider');
            app.MultipleprofilesSwitch.ValueChangedFcn = createCallbackFcn(app, @MultipleprofilesSwitchValueChanged, true);
            app.SpeedKnobLabel = uilabel(app.SetConditionsPanel);
            app.SpeedKnobLabel.Text = 'Speed';
            app.SpeedKnob = uiknob(app.SetConditionsPanel, 'continuous');
            app.SpeedKnob.Limits = [1 6];
            app.SpeedKnob.MajorTicks = [1 2 3 4 5 6];
            app.SpeedKnob.ValueChangedFcn = createCallbackFcn(app, @SpeedKnobValueChanged, true);
            app.SpeedKnob.MinorTicks = [];
            app.SpeedKnob.Value = 3;
            app.BeachProfilesLabel = uilabel(app.UIFigure);
            app.BeachProfilesLabel.Text = 'Beach Profiles';
            app.profileAxes = uiaxes(app.UIFigure);
            app.timeAxes = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeachProfiles

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end