classdef CEM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        coastAxes                    matlab.ui.control.UIAxes
        SetConditionsPanel           matlab.ui.container.Panel
        waveangledegEditFieldLabel   matlab.ui.control.Label
        waveangleEditField           matlab.ui.control.NumericEditField
        shorelineshapeDropDownLabel  matlab.ui.control.Label
        shorelineshapeDropDown       matlab.ui.control.DropDown
        CoastlineEvolutionLabel      matlab.ui.control.Label
        ControlsPanel                matlab.ui.container.Panel
        InitializeButton             matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        SpeedKnobLabel               matlab.ui.control.Label
        SpeedKnob                    matlab.ui.control.Knob
        fluxAxes                     matlab.ui.control.UIAxes
        HelpButton                   matlab.ui.control.Button
        LongshoreposmSliderLabel     matlab.ui.control.Label
        LongshoreposmSlider          matlab.ui.control.Slider
    end


    properties (Access = private)
        x                     % crosshore shoreline positions
        y                     % longshore shoreline positions
        y_lims = 30;
        alpha                 % wave angle relative to horizontal
        wave_height = 2;      % local wave height (m)
        
        rho = 1020            % kg/m^3
        g = 9.8               % m^2/s
        
        wait_time             % set by speed slider
        stop                  % variable used to stop run
    end

    methods (Access = private)
    
        function [l1, l2] = plot_alpha(app, i, shore_angle, dir)
                % plot wave and shoreline angle
                ax = app.coastAxes;
                l1 = plot(ax, [i-3, i+3], [-(3*sind(shore_angle)) + app.y(i),...
                    (3*sind(shore_angle)) + app.y(i)],...
                    'm-', 'linewidth', 2);
                if dir > 0
                    l2 = plot(ax, [i-3, i+3], [-(3*sind(shore_angle)) + app.y(i),...
                        (6*sind(app.alpha)) - (3*sind(shore_angle)) + app.y(i)],...
                    'm-', 'linewidth', 2);
                else
                    l2 = plot(ax, [i-3, i+3], [-(6*sind(app.alpha)) + (3*sind(shore_angle)) + app.y(i),...
                        (3*sind(shore_angle)) + app.y(i)],...
                    'm-', 'linewidth', 2);
                end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.stop = 1;
            app.RunButton.Enable = 'off';
            app.StopButton.Enable = 'off';
            
            % set shoreline axes
            ax = app.coastAxes;
            xticklabels(ax, []);
            ylabel(ax, 'cross-shore dist. (m)');
            
            % set flux axes
            ax = app.fluxAxes;
            set(ax, 'defaultAxesColorOrder', [[0 0 0]; [0 0 0]]);
            xlim(ax, [1 150]);
            yyaxis(ax, 'left');
            ylabel(ax, 'sed. flux');
            yticklabels(ax, []);
            yyaxis(ax, 'right');
            ylabel(ax, 'vol. change');
            yticklabels(ax, []);
            xticklabels(ax, []);
            
            app.Initialize();
            
            
        end

        % Button pushed function: InitializeButton
        function Initialize(app, event)
            % initialize user input fields
            app.alpha = app.waveangleEditField.Value;
            app.wait_time = .01* (10/app.SpeedKnob.Value);            
            
            yyaxis(app.fluxAxes, 'left');
            cla(app.fluxAxes);
            yyaxis(app.fluxAxes, 'right');
            cla(app.fluxAxes);
            
            % initilize coastline graph
            switch app.shorelineshapeDropDown.Value
                case 'linear'
                    app.y = (ones(1, 152)).*6;
                    
                case 'headland'
                    app.y = -6.*cos(linspace(0, 2*pi, 152)) + 6;
                    
                case 'bay'
                    app.y = 6.*cos(linspace(0, 2*pi, 152)) + 6;
                    
            end
            
            app.x = 0:1:151;            
            
            % build bathy grid
            h = nan(app.y_lims+1, length(app.x));
            y_step = 0.5;
            for r = 1:1:2*app.y_lims+1
                for col = 1:length(app.x)
                    if r*y_step <= app.y(col)
                        h(r, col) = nan; % land
                    else
                        h(r, col) = -0.015 * ((r*y_step) - app.y(col)); % depth (m)
                    end
                end
            end
            
            
            % plot bathy
            ax = app.coastAxes;
            cla(ax);        
            colormap(ax, 'winter');
            set(ax, 'color', 'yellow');
            contourf(ax, app.x, (0:y_step:app.y_lims), h, 12);
            hold(ax, 'on');
            plot(ax, app.x, app.y, 'k-', 'linewidth', 2); ax.XLim = [0 length(app.x)-1]; ax.YLim = [0 app.y_lims];
            axis(ax, 'equal');
            
            % allow run
            app.RunButton.Enable = 'on';
            
            % initialize current run
            app.stop = 0;
        end

        % Button pushed function: RunButton
        function Run(app, event)
            % disable initialize button while running
            app.InitializeButton.Enable = 'off';
            % disable run button while running
            app.RunButton.Enable = 'off';
            % enable stop button
            app.StopButton.Enable = 'on';
            app.stop = 0;
            
            % set up axes
            hold(app.coastAxes, 'on');
            hold(app.fluxAxes, 'on');
            title(app.fluxAxes, 'flux out');
            
            vol_out = nan(1, 152);
            vol_in = zeros(1, 152);        
            shore_angle = vol_in;
            dir = vol_in;
            h_in = gobjects(0);
            
            % start loop
            for i = (1:152)
                if app.stop
                    break
                end
                % get local shoreline angle
                if i == 1
                    shore_angle(i) = atan2d(app.y(i+1) - app.y(i), 1);
                elseif i == 152
                    shore_angle(i) = atan2d(app.y(i) - app.y(i-1), 1);
                else
                    shore_angle(i) = atan2d(app.y(i+1) - app.y(i-1), 2);
                end
                
                % convert angle to CEM angles
                cem_angle = shore_angle(i);
                if cem_angle > 90
                    cem_angle = 90 - angle(i);
                end
                alpha = app.alpha - cem_angle;
                
                % get transport dir
                dir(i) = alpha/abs(alpha);
                
                [l1, l2] = app.plot_alpha(i, shore_angle(i), dir(i));                
                                
                % plot flux out
                ax = app.fluxAxes;
                yyaxis(ax, 'left');
                vol_out(i) = abs(1.1 * app.rho * app.g^(3/2) * app.wave_height^(5/2) * cosd(alpha) * sind(alpha));
                delete(h_in);
                h_in = plot(ax, (0:1:i-1), (vol_out(1:1:i)), 'b-', 'linewidth', 1.8);
                if i == 1
                    ylim(ax, [0 1])
                else
                    ylim(ax, [nanmin(vol_out) nanmax(vol_out)]);
                end

                pause(app.wait_time)
                delete(l1); delete(l2);
            end
            
            
            % get volume in
            h_out = gobjects(0);
            for i = (152:-1:1)
                if app.stop
                    break
                end
                j = i + dir(i);  
                
                % plot angle and direction
                [l1, l2] = app.plot_alpha(i, shore_angle(i), dir(i));
                if dir(i) > 0
                    arrow = plot(app.coastAxes, app.x(i), app.y(i), 'k>', 'markersize', 8, ...
                        'markerfacecolor', 'yellow');
                else                    
                    arrow = plot(app.coastAxes, app.x(i), app.y(i), 'k<', 'markersize', 8, ...
                        'markerfacecolor', 'yellow');
                end
                
                if j > 0 && j < 152
                    vol_in(j) = vol_in(j) + vol_out(i);
                    ax = app.fluxAxes;
                    yyaxis(ax, 'left');
                    title(ax, 'flux in')
                    delete(h_out);
                    h_out = plot(ax, (j-1:1:150), vol_in(j:1:151), 'r-', 'linewidth', 1.8);
                end 
                    
                pause(app.wait_time);
                delete(l1); delete(l2); delete(arrow);
            end
            
            if app.stop
                return
            end
            eroding = find(vol_in(2:151) > vol_out(2:151));
            accreting = find(vol_out(2:151) > vol_in(2:151));
            patch(ax, [(app.x(accreting)) fliplr(app.x(accreting))], [vol_in(accreting) fliplr(vol_out(accreting))],...
                [0 1 1], 'facealpha', 0.4, 'edgecolor', 'none');
            patch(ax, [app.x(eroding) fliplr(app.x(eroding))], [vol_in(eroding) fliplr(vol_out(eroding))],...
                [1 .5 0], 'facealpha', 0.6, 'edgecolor', 'none');
            
            % get volume change
            vol_change = vol_in(2:151) - vol_out(2:151);
            discrete_errors = find(vol_change(vol_in(2:151) == 0 & vol_out(2:151) == 0));
            vol_change(discrete_errors) = ((vol_in(discrete_errors) - vol_out(discrete_errors))...
                + (vol_in(discrete_errors+2) - vol_out(discrete_errors+2)))/2;
            
            % plot volume change
            title(ax, '')  ;
            yyaxis(ax, 'right');
            plot(ax, [app.x(1) app.x(end)], [0 0], 'k--', 'linewidth', 1.2);
            plot(ax, app.x(2:151), vol_change, 'k--', 'linewidth', 2);
            pause(app.wait_time)
            ylim(ax, [min(vol_change) max(vol_change)]);
            
            if app.stop
                return
            end
            % plot shoreline advance/retreat
            ax = app.coastAxes;
            % create own color map
            colors = redblue(100);
            vol_range = 2*max(abs(min(vol_change)), abs(max(vol_change)));
            % shift to all positive volumes
            vol_change = vol_change + vol_range/2;
            for i = (1:length(vol_change))
                vol = vol_change(i);
                color_index = 1+floor(99*(vol/vol_range));
                plot(ax, [app.x(i+1), app.x(i+1)], [app.y(i+1)+1, app.y(i+1)+3],...
                    '-','color', colors(color_index, :),'linewidth', 4);
            end          
            
            % end procedure
            app.InitializeButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.stop = 1;
            hold(app.coastAxes, 'off');
            hold(app.fluxAxes, 'off');
        end

        % Value changed function: SpeedKnob
        function SpeedKnobValueChanged(app, event)
            app.wait_time = .01* (10/app.SpeedKnob.Value);
        end

        % Button pushed function: StopButton
        function Stop(app, event)
            % re-enable initialize and start button
            app.InitializeButton.Enable = 'on';
            % disable stop button 
            app.StopButton.Enable = 'off';            
            % stop run
            app.stop = 1;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.coastAxes = uiaxes(app.UIFigure);
            app.SetConditionsPanel = uipanel(app.UIFigure);
            app.SetConditionsPanel.Title = 'Set Conditions';
            app.waveangledegEditFieldLabel = uilabel(app.SetConditionsPanel);
            app.waveangledegEditFieldLabel.Text = 'wave angle (deg)';
            app.waveangleEditField = uieditfield(app.SetConditionsPanel, 'numeric');
            app.waveangleEditField.Limits = [-90 90];
            app.waveangleEditField.RoundFractionalValues = 'on';
            app.waveangleEditField.Value = 45;
            app.shorelineshapeDropDownLabel = uilabel(app.SetConditionsPanel);
            app.shorelineshapeDropDownLabel.Text = 'shoreline shape';
            app.shorelineshapeDropDown = uidropdown(app.SetConditionsPanel);
            app.shorelineshapeDropDown.Items = {'linear', 'headland', 'bay'};
            app.shorelineshapeDropDown.Value = 'headland';
            app.CoastlineEvolutionLabel = uilabel(app.UIFigure);
            app.CoastlineEvolutionLabel.Text = 'Coastline Evolution';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.InitializeButton = uibutton(app.ControlsPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @Initialize, true);
            app.InitializeButton.Text = 'Initialize';
            app.RunButton = uibutton(app.ControlsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @Run, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.ControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @Stop, true);
            app.StopButton.Text = 'Stop';
            app.SpeedKnobLabel = uilabel(app.ControlsPanel);
            app.SpeedKnobLabel.Text = 'Speed';
            app.SpeedKnob = uiknob(app.ControlsPanel, 'continuous');
            app.SpeedKnob.Limits = [1 10];
            app.SpeedKnob.ValueChangedFcn = createCallbackFcn(app, @SpeedKnobValueChanged, true);
            app.SpeedKnob.MinorTicks = [];
            app.SpeedKnob.Value = 4;
            app.fluxAxes = uiaxes(app.UIFigure);
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.Icon = 'help_icon.png';
            app.HelpButton.Text = 'Help';
            app.LongshoreposmSliderLabel = uilabel(app.UIFigure);
            app.LongshoreposmSliderLabel.Text = 'Longshore pos. (m)';
            app.LongshoreposmSlider = uislider(app.UIFigure);
            app.LongshoreposmSlider.Limits = [0 150];
            app.LongshoreposmSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150];
        end
    end

    methods (Access = public)

        % Construct app
        function app = CEM

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end