classdef HillDiffSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        SetConditionsPanel          matlab.ui.container.Panel
        noiseEditFieldLabel         matlab.ui.control.Label
        noiseEditField              matlab.ui.control.NumericEditField
        kappaEditFieldLabel         matlab.ui.control.Label
        kappaEditField              matlab.ui.control.NumericEditField
        upliftrateEditFieldLabel    matlab.ui.control.Label
        upliftrateEditField         matlab.ui.control.NumericEditField
        durationEditFieldLabel      matlab.ui.control.Label
        durationEditField           matlab.ui.control.NumericEditField
        AddChannelCheckBox          matlab.ui.control.CheckBox
        incisionrateEditFieldLabel  matlab.ui.control.Label
        incisionrateEditField       matlab.ui.control.NumericEditField
        HillslopeDiffusionSimulatorLabel  matlab.ui.control.Label
        ControlsPanel               matlab.ui.container.Panel
        InitializeButton            matlab.ui.control.Button
        RunButton                   matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        Timestep                    matlab.ui.control.Label
    end


    properties (Access = private)
        x                     % distances (m)
        z                     % surface elevations (m)
        noise                 % vertical relief (m)
        kappa                 % m^2/kyr
        uplift                % m/kyr
        duration              % kyr
        include_channel = 0   % 0 or 1
        incision_rate = 0     % m/kyr
        
        stop_time             % variables used to communicate
        current_time          % between run and stop
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RunButton.Enable = 'off';
            app.StopButton.Enable = 'off';
        end

        % Button pushed function: InitializeButton
        function Initialize(app, event)
            % set position values
            app.x = 1:10:1001;
            app.z = rand(1, 101);
            % initialize user input fields
            app.noise = app.noiseEditField.Value;
            app.kappa = app.kappaEditField.Value;
            app.uplift = app.upliftrateEditField.Value;
            app.duration = app.durationEditField.Value;
            app.include_channel = app.AddChannelCheckBox.Value;
            app.incision_rate = app.incisionrateEditField.Value;
            
            % add random noise and fixed points
            app.z = app.z .* app.noise;
            app.z(1) = 0; app.z(68) = 0; app.z(end) = 0;
            
            % plot initial conditions
            ax = app.UIAxes;
            plot(ax, app.x, app.z, 'b'); ax.XLim = [0 1000]; ax.YLim = [-2 28];
            
            % allow run
            app.RunButton.Enable = 'on';
            
            % initialize current run
            app.current_time = 1;
            app.Timestep.Text = 't = 0 (kyr)';
        end

        % Button pushed function: RunButton
        function Run(app, event)
            % disable initialize button while running
            app.InitializeButton.Enable = 'off';
            % disable run button while running
            app.RunButton.Enable = 'off';
            % enable stop button
            app.StopButton.Enable = 'on';
            
            % start loop
            app.stop_time = app.duration;
            while app.current_time <= app.stop_time
                % update surface elvation
                dRdt = app.kappa .* del2(app.z);
                app.z = app.z + dRdt + app.uplift;
                % incise if channel present
                if app.include_channel
                    app.z(67:69) = app.z(68) + app.incision_rate;
                end
                % fix edges
                app.z(1) = app.z(2); app.z(end) = app.z(end-1);                
                % plot timestep
                ax = app.UIAxes;
                plot(ax, app.x, app.z, 'b'); ax.XLim = [0 1000]; ax.YLim = [-2 28];
                pause(0.1);
                % update time
                app.Timestep.Text = ['t = ', num2str(app.current_time), ' (kyr)'];
                app.current_time = app.current_time + 1;
            end
            
            % re-enable initialize button
            app.InitializeButton.Enable = 'on';
            % disable stop button
            app.StopButton.Enable = 'off';
        end

        % Button pushed function: StopButton
        function Stop(app, event)
            % re-enable initialize button
            app.InitializeButton.Enable = 'on';
            % re-enable run button
            app.RunButton.Enable = 'on';
            % disable stop button
            app.StopButton.Enable = 'off';
            
            % stop run loop
            app.stop_time = 0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'horizontal distance (m)')
            ylabel(app.UIAxes, 'surface elevation (m)')
            app.SetConditionsPanel = uipanel(app.UIFigure);
            app.SetConditionsPanel.Title = 'Set Conditions';
            app.noiseEditFieldLabel = uilabel(app.SetConditionsPanel);
            app.noiseEditFieldLabel.Text = 'noise';
            app.noiseEditField = uieditfield(app.SetConditionsPanel, 'numeric');
            app.noiseEditField.Limits = [0 10];
            app.noiseEditField.Value = 5;
            app.kappaEditFieldLabel = uilabel(app.SetConditionsPanel);
            app.kappaEditFieldLabel.Text = 'kappa';
            app.kappaEditField = uieditfield(app.SetConditionsPanel, 'numeric');
            app.kappaEditField.Limits = [0 1];
            app.kappaEditField.Value = 0.5;
            app.upliftrateEditFieldLabel = uilabel(app.SetConditionsPanel);
            app.upliftrateEditFieldLabel.Text = 'uplift rate';
            app.upliftrateEditField = uieditfield(app.SetConditionsPanel, 'numeric');
            app.upliftrateEditField.Limits = [0 1];
            app.upliftrateEditField.Value = 0.2;
            app.durationEditFieldLabel = uilabel(app.SetConditionsPanel);
            app.durationEditFieldLabel.Text = 'duration';
            app.durationEditField = uieditfield(app.SetConditionsPanel, 'numeric');
            app.durationEditField.Limits = [0 1000];
            app.durationEditField.Value = 100;
            app.AddChannelCheckBox = uicheckbox(app.SetConditionsPanel);
            app.AddChannelCheckBox.Text = 'Add Channel';
            app.incisionrateEditFieldLabel = uilabel(app.SetConditionsPanel);
            app.incisionrateEditFieldLabel.Text = 'incision rate';
            app.incisionrateEditField = uieditfield(app.SetConditionsPanel, 'numeric');
            app.incisionrateEditField.Limits = [-5 0];
            app.incisionrateEditField.Value = -0.2;
            app.HillslopeDiffusionSimulatorLabel = uilabel(app.UIFigure);
            app.HillslopeDiffusionSimulatorLabel.Text = 'Hillslope Diffusion Simulator';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.InitializeButton = uibutton(app.ControlsPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @Initialize, true);
            app.InitializeButton.Text = 'Initialize';
            app.RunButton = uibutton(app.ControlsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @Run, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.ControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @Stop, true);
            app.StopButton.Text = 'Stop';
            app.Timestep = uilabel(app.UIFigure);
            app.Timestep.Text = 't = 0 (kyr)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = HillDiffSim

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end