classdef PeakIsoSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        SetConditionsPanel            matlab.ui.container.Panel
        incisionrateEditField_2Label  matlab.ui.control.Label
        incisionrateEditField         matlab.ui.control.NumericEditField
        crustalthicknessLabel         matlab.ui.control.Label
        crustalthicknessEditField     matlab.ui.control.NumericEditField
        durationEditFieldLabel        matlab.ui.control.Label
        durationEditField             matlab.ui.control.NumericEditField
        mmyrLabel                     matlab.ui.control.Label
        kmLabel                       matlab.ui.control.Label
        myrLabel                      matlab.ui.control.Label
        PeakIsostasySimulatorLabel    matlab.ui.control.Label
        ControlsPanel                 matlab.ui.container.Panel
        InitializeButton              matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        Timestep                      matlab.ui.control.Label
    end


    properties (Access = private)
        x = 0:10:1200
        xf = 0:200:1200        % fold points
        crust
        folded_crust
        incision_rate
        elevation
        boundary
        mean_elevation
        surf_init
        moho_init
        
        rho_contcrust = 2700;
        rho_mantle = 3300;
        dt = 0.1;
        
        stop_time             % variables used to communicate
        current_time          % between run and stop
        duration
    end

    methods (Access = private)
    
        function update_plot(app)
            ax = app.UIAxes;
            plot(ax, app.x, app.elevation, 'linewidth', 2); hold(ax); % surface line
            plot(ax, app.x, app.boundary, 'r', 'linewidth', 2); % boundary line
            plot(ax, app.x, app.mean_elevation, 'k-.', 'linewidth', 2) % mean elevation line
            axis(ax, [0 1200 app.surf_init - app.crust - 5, app.surf_init + 10]);
            text(ax, 100, app.moho_init-3, 'Mantle', 'Fontsize', 16);
            text(ax, 100, app.surf_init - app.crust/2, 'crust', 'Fontsize', 16);
            text(ax, 300, app.mean_elevation(1) - 1, ...
                ['Mean Elev. = ', num2str(round(10 * app.mean_elevation(1))/10), 'km'], 'Fontsize', 16);
            hold(ax);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RunButton.Enable = 'off';
            app.StopButton.Enable = 'off';
        end

        % Button pushed function: InitializeButton
        function Initialize(app, event)
            % allow run
            app.RunButton.Enable = 'on';
            
            % set initial conditions
            app.crust = app.crustalthicknessEditField.Value;
            app.duration = app.durationEditField.Value;
            app.incision_rate = app.incisionrateEditField.Value;
            
            app.elevation = app.crust*ones(size(app.x)) - (0.81 * app.crust);
            app.mean_elevation = app.elevation;
            app.surf_init = app.elevation(1);
            app.moho_init = app.surf_init - app.crust;
            app.boundary = app.elevation - app.crust;
            app.folded_crust = ones(1, 7)*app.crust;
            
            % plot
            app.update_plot();
            
            % initialize current run
            app.current_time = 0;
            app.Timestep.Text = 't = 0 (myr)';
        end

        % Button pushed function: RunButton
        function Run(app, event)
            % disable initialize button while running
            app.InitializeButton.Enable = 'off';
            % disable run button while running
            app.RunButton.Enable = 'off';
            % enable stop button
            app.StopButton.Enable = 'on';
            
            % start loop
            app.stop_time = app.duration;
            while app.current_time <= app.stop_time
                % incise valleys
                app.folded_crust([2 4 6]) = app.folded_crust([2 4 6]) - (app.incision_rate*app.dt);
                folded_interp = interp1(app.xf, app.folded_crust, app.x);
                folded_mean = mean(folded_interp);
                %isostatic rebound
                M = (app.rho_contcrust/app.rho_mantle) * (app.crust - folded_mean);
                if M > min(folded_interp)
                    app.Stop();
                end
                app.elevation = M + folded_interp - (0.81*app.crust);
                app.boundary = app.elevation - folded_interp;
                app.mean_elevation = mean(app.elevation) * ones(size(app.x));
                % update plot
                app.update_plot();
                pause(0.1);
                % update time
                app.Timestep.Text = ['t = ', num2str(app.current_time), ' (myr)'];
                app.current_time = app.current_time + app.dt;
            end
            
            % final plots
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, app.x, app.moho_init*ones(size(app.x)), 'r-.', 'linewidth', 2)
            plot(app.UIAxes, app.x, (app.surf_init*ones(size(app.x))), 'b-.', 'linewidth', 2)
            
            % re-enable initialize button
            app.InitializeButton.Enable = 'on';
            % disable stop button
            app.StopButton.Enable = 'off';
        end

        % Button pushed function: StopButton
        function Stop(app, event)
            % re-enable initialize button
            app.InitializeButton.Enable = 'on';
            % re-enable run button
            app.RunButton.Enable = 'on';
            % disable stop button
            app.StopButton.Enable = 'off';
            
            % stop run loop
            app.stop_time = 0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'horizontal distance (km)')
            ylabel(app.UIAxes, 'surface elevation (m)')
            app.SetConditionsPanel = uipanel(app.UIFigure);
            app.SetConditionsPanel.Title = 'Set Conditions';
            app.incisionrateEditField_2Label = uilabel(app.SetConditionsPanel);
            app.incisionrateEditField_2Label.Text = 'incision rate';
            app.incisionrateEditField = uieditfield(app.SetConditionsPanel, 'numeric');
            app.incisionrateEditField.Limits = [0 10];
            app.incisionrateEditField.Value = 1;
            app.crustalthicknessLabel = uilabel(app.SetConditionsPanel);
            app.crustalthicknessLabel.Text = 'crustal thickness';
            app.crustalthicknessEditField = uieditfield(app.SetConditionsPanel, 'numeric');
            app.crustalthicknessEditField.Limits = [5 100];
            app.crustalthicknessEditField.ValueDisplayFormat = '%11.5g';
            app.crustalthicknessEditField.Value = 35;
            app.durationEditFieldLabel = uilabel(app.SetConditionsPanel);
            app.durationEditFieldLabel.Text = 'duration';
            app.durationEditField = uieditfield(app.SetConditionsPanel, 'numeric');
            app.durationEditField.Limits = [0 1000];
            app.durationEditField.Value = 100;
            app.mmyrLabel = uilabel(app.SetConditionsPanel);
            app.mmyrLabel.Text = '(mm/yr)';
            app.kmLabel = uilabel(app.SetConditionsPanel);
            app.kmLabel.Text = '(km)';
            app.myrLabel = uilabel(app.SetConditionsPanel);
            app.myrLabel.Text = '(myr)';
            app.PeakIsostasySimulatorLabel = uilabel(app.UIFigure);
            app.PeakIsostasySimulatorLabel.Text = 'Peak Isostasy Simulator';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.InitializeButton = uibutton(app.ControlsPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @Initialize, true);
            app.InitializeButton.Text = 'Initialize';
            app.RunButton = uibutton(app.ControlsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @Run, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.ControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @Stop, true);
            app.StopButton.Text = 'Stop';
            app.Timestep = uilabel(app.UIFigure);
            app.Timestep.Text = 't = 0 (myr)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PeakIsoSim

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end