classdef PeriglacialSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ControlsPanel     matlab.ui.container.Panel
        InitializeButton  matlab.ui.control.Button
        RunButton         matlab.ui.control.Button
        StopButton        matlab.ui.control.Button
        surf_axes         matlab.ui.control.UIAxes
        prof_axes         matlab.ui.control.UIAxes
        Panel             matlab.ui.container.Panel
        PeriglacialGroundTemperatureLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        zstar
        z
        dTdz
        t
        x
        Tamp
        P
        surf_t
        subsurf_t
        stop_time             % variables used to communicate
        current_time          % between run and stop
        duration
    end

    methods (Access = private)
    
        function update_plot(app)
            % plot surface temperature
            surf = app.surf_axes;
            plot(surf, [app.x(1) app.x(end)], [0 0], 'r--', 'linewidth', 2), hold(surf);
            plot(surf, app.x, app.surf_t, 'linewidth', 2);
            plot(surf, app.x(app.current_time), app.surf_t(app.current_time), 'ko', 'markersize', 15, 'markerfacecolor', 'r');
            surf.YLim = [-30 5];
            hold(surf);
            
            % plot temperature profile
            subsurf = app.prof_axes;
            plot(subsurf, app.subsurf_t(:, app.current_time), app.z, 'linewidth', 2); hold(subsurf);
            subsurf.XLim = [-25 5]; subsurf.YLim = [0 8];
            plot(subsurf, [0 0], [0 20], 'r--', 'linewidth', 2); axis(subsurf, 'ij');
            hold(subsurf);
        end
        
        function get_temps(app)
            % surface temp
            app.surf_t = app.Tamp * sin((2*pi*app.t/app.P)) - 10;
            
            % subsurface temps
            Ts = -10*ones(length(app.z), 1);
            app.subsurf_t = zeros(length(app.z), length(app.t));
            for time = 1:length(app.t)
                app.subsurf_t(:,time)=(Ts)-((app.dTdz).*app.z)+(app.Tamp.*exp(-app.z./app.zstar)...
                    .*sin((2*pi*app.t(time)/app.P)-(app.z./app.zstar)));
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RunButton.Enable = 'off';
            app.StopButton.Enable = 'off';
        end

        % Button pushed function: InitializeButton
        function Initialize(app, event)
            % constants
            kappa = 1*((1/1000)^2);
            app.P = pi*1e7;
            app.Tamp = 15;
            % set initial conditions
            app.zstar = sqrt(kappa*app.P/pi);
            app.z = (0:0.1:20)';
            app.dTdz = -0.025;
            app.t = 0:app.P/50:3*app.P;
            app.x = app.t./app.P;
            
            % calc all temps
            app.get_temps();
            
            % allow run
            app.RunButton.Enable = 'on';
            
            % initialize current run
            app.current_time = 1;
            app.duration = length(app.x);
            
            % plot
            app.update_plot();
            
        end

        % Button pushed function: RunButton
        function Run(app, event)
            % disable initialize button while running
            app.InitializeButton.Enable = 'off';
            % disable run button while running
            app.RunButton.Enable = 'off';
            % enable stop button
            app.StopButton.Enable = 'on';
            
            % start loop
            app.stop_time = app.duration;
            while app.current_time <= app.stop_time
                % replot
                app.update_plot();
                pause(0.05);
                % update time
                app.current_time = app.current_time + 1;
            end
            
            % re-enable initialize button
            app.InitializeButton.Enable = 'on';
            % disable stop button
            app.StopButton.Enable = 'off';
        end

        % Button pushed function: StopButton
        function Stop(app, event)
            % re-enable initialize button
            app.InitializeButton.Enable = 'on';
            % re-enable run button
            app.RunButton.Enable = 'on';
            % disable stop button
            app.StopButton.Enable = 'off';
            
            % stop run loop
            app.stop_time = 0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.InitializeButton = uibutton(app.ControlsPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @Initialize, true);
            app.InitializeButton.Text = 'Initialize';
            app.RunButton = uibutton(app.ControlsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @Run, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.ControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @Stop, true);
            app.StopButton.Text = 'Stop';
            app.surf_axes = uiaxes(app.UIFigure);
            title(app.surf_axes, 'Surface Temperature Time Series')
            xlabel(app.surf_axes, 'Time (years)')
            ylabel(app.surf_axes, 'Temperature (deg C)')
            app.prof_axes = uiaxes(app.UIFigure);
            title(app.prof_axes, 'Ground Temperature Profile')
            xlabel(app.prof_axes, 'Temperature (deg C)')
            ylabel(app.prof_axes, 'Depth (m)')
            app.Panel = uipanel(app.UIFigure);
            app.PeriglacialGroundTemperatureLabel = uilabel(app.Panel);
            app.PeriglacialGroundTemperatureLabel.Text = 'Periglacial Ground Temperature';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PeriglacialSim

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end