classdef Refraction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ControlsPanel                matlab.ui.container.Panel
        InitializeButton             matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        SetConditionsPanel           matlab.ui.container.Panel
        shorelineshapeDropDownLabel  matlab.ui.control.Label
        shorelineshapeDropDown       matlab.ui.control.DropDown
        waveperiodsecSpinnerLabel    matlab.ui.control.Label
        waveperiodSpinner            matlab.ui.control.Spinner
        contoursButtonGroup          matlab.ui.container.ButtonGroup
        parallelButton               matlab.ui.control.RadioButton
        shoalsButton                 matlab.ui.control.RadioButton
        borrowpitButton              matlab.ui.control.RadioButton
        waveangledegLabel            matlab.ui.control.Label
        waveangleSpinner             matlab.ui.control.Spinner
        WaveRefractionLabel          matlab.ui.control.Label
        SpeedKnobLabel               matlab.ui.control.Label
        SpeedKnob                    matlab.ui.control.Knob
        coastAxes                    matlab.ui.control.UIAxes
        HelpButton                   matlab.ui.control.Button
    end


    properties (Access = private)
        wave_angle_deep                  % degrees
        T                                % wave period
        slope = 0.01;                    % shoreface slope
        
        x_pos                            % longshore wave positions
        y_pos                            % cross-shore wave positions
        x_final
        y_final                          % final wave ray positions
        num_rays = 20;
        
        x_step = .05;                    % longshore cell size (m)
        x                                % longshore domain
        y_step = .05;                    % cross-shore cell size (m)
        y                                % cross-shore domain
        h                                % bathy grid
        grad                             % bathy gradient
        shoreline                        % y pos of shoreline
        c                                % vector of wave ray celerities
        wave_angles                      % vector of wave crest angles
        
        stopped                          % signifies app is stopped
        wait_time                        % run speed
    end

    methods (Access = private)
    
        function removeWaveRaysHelper(app, indices)            
                app.x_pos(indices) = [];
                app.y_pos(indices) = [];
                app.c(indices) = [];
                app.wave_angles(indices) = [];
        end
        
        function [x_dist, y_dist] = mergeRays(app, x_dist, y_dist)
            x_new = app.x_pos + x_dist;
            cross_indices = find(x_new(1:end-1) > x_new(2:end));
            if ~isempty(cross_indices)
                % get combined angle
                combined_x = x_dist(cross_indices+1) + x_dist(cross_indices);
                combined_y = y_dist(cross_indices+1) + y_dist(cross_indices);
                angles = atan2(combined_y, combined_x);
                app.wave_angles(cross_indices) = angles;
                app.wave_angles(cross_indices+1) = angles;                
                % recalc positions
                x_dist = ((app.T/4)*app.c).*sind(app.wave_angles);
                y_dist = ((app.T/4)*app.c).*cosd(app.wave_angles);
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.stopped = 1;
            app.RunButton.Enable = 'off';
            app.StopButton.Enable = 'off';
            
            % initialize global values
            app.x = (0:app.x_step:15);
            app.y = (0:app.y_step:10);
            app.h = zeros(length(app.y), length(app.x));
                        
            % set up shoreline axes
            ax = app.coastAxes;
            colormap(ax, 'winter');
            cb = colorbar(ax, 'fontsize', 12);
            ylabel(cb, 'depth (m)', 'fontsize', 14); 
            ylim(ax, [0 app.y(end)]);
            xlim(ax, [0, app.x(end)]);
            ylabel(ax, 'cross-shore pos. (km)');   
            xlabel(ax, 'long-shore pos. (km)');
            axis(ax, 'equal');
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            refracHelpApp
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            % init props
            app.x_pos = nan(1, app.num_rays); 
            app.y_pos = nan(1, app.num_rays);
            app.x_final = [];
            app.y_final = [];
            app.SpeedKnobValueChanged();
            
            % initialize user input
            app.wave_angle_deep = mod(app.waveangleSpinner.Value, 360);
            app.T = app.waveperiodSpinner.Value;
            
            % initialize global values
            c0 = ((9.81*app.T)/(2*pi))/1000; % deep water celerity in km
            app.c = c0*ones(size(app.x_pos)); % vector of wave ray celerities
            app.wave_angles = app.wave_angle_deep*ones(size(app.c));
            
            % initialize coastline
            switch app.shorelineshapeDropDown.Value
                case 'linear'
                    app.shoreline = (ones(1, length(app.x))).*10;
                    
                case 'headland'
                    app.shoreline = -10.*cos(linspace(0, 2*pi, length(app.x))) + 11;
                    
                case 'bay'
                    app.shoreline = 10.*cos(linspace(0, 2*pi, length(app.x))) + 11;                    
            end
            
            % build bathy grid
            for r = 1:length(app.y)
                for col = 1:length(app.x)
                    if r <= app.shoreline(col)
                        app.h(r, col) = nan; % land
                    else
                        app.h(r, col) = -1000 * (app.y_step * app.slope) * (r - app.shoreline(col)); % depth (m)
                    end
                end
            end
            
            radius = 1;    % radius of bathy feature (km)
            height = 0.01;    % max dz of bathy feature (km)
            center_r = floor(length(app.y)/3);
            center_c = floor(length(app.x)/2);
            feature_slope = height/radius;
            
            % add shoals
            if app.shoalsButton.Value
                for r = (center_r - floor(radius/app.y_step):1:center_r + floor(radius/app.y_step))
                    for col = (center_c - floor(radius/app.x_step):1:center_c + floor(radius/app.x_step))
                        dist = sqrt(((center_r-r) * app.y_step)^2 + ((center_c-col) * app.x_step)^2);
                        if dist <= radius
                            elev = feature_slope*(radius-dist);
                            app.h(r, col) = app.h(r, col) + 1000*elev;
                        end
                    end
                end
            end
            
            % add borrow pit
            if app.borrowpitButton.Value
                for r = (center_r - floor(radius/app.y_step):1:center_r + floor(radius/app.y_step))
                    for col = (center_c - floor(radius/app.x_step):1:center_c + floor(radius/app.x_step))
                        dist = sqrt(((center_r-r) * app.y_step)^2 + ((center_c-col) * app.x_step)^2);
                        if dist <= radius
                            elev = feature_slope*(radius-dist);
                            app.h(r, col) = app.h(r, col) - 1000*elev;
                        end
                    end
                end
            end
                        
            % generate bathy iso-gradient angles
            [px, py] = gradient(app.h);
            app.grad = atan2d(py, px);
            app.grad = app.grad+90;        % get normal slope
            app.grad = mod(app.grad, 360); % convert to 0 to 360
            
            % plot bathy
            ax = app.coastAxes;
            cla(ax);        
            set(ax, 'color', 'yellow');
            contourf(ax, app.x, app.y, app.h, 20);
            hold(ax, 'on');
            plot(ax, app.x, app.shoreline.*app.y_step, 'k-', 'linewidth', 2);
            
            % initialize wave vector
            crest_length = length(app.x)*app.x_step;
            crest_pos = linspace(0, crest_length, app.num_rays); 
            xdists = crest_pos.*cosd(app.wave_angle_deep);
            ydists = crest_pos.*sind(app.wave_angle_deep);
            if sind(app.wave_angle_deep) < 0 % anchor to left side
                app.y_pos = app.y(end)+ydists;
                app.x_pos = app.x(end)-(xdists(end)-xdists);
            else % anchor to right side
                app.y_pos = app.y(end)-(ydists(end)-ydists);
                app.x_pos = xdists;
            end
            
            % move wave crest to fit
            min_y = min(app.y_pos);
            if min_y < 6.5
                app.y_pos = app.y_pos + (6.5 - min_y);
                out_of_bounds = find (app.y_pos > app.y(end));
                app.removeWaveRaysHelper(out_of_bounds);
            end
            
            % plot wave crest
            plot(ax, app.x_pos, app.y_pos, 'd-k', 'markerfacecolor', 'k', 'markersize', 6, 'linewidth', 2);            
            
            % initialize run settings
            app.RunButton.Enable = 'on';
            app.stopped = 0;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % disable initialize button while running
            app.InitializeButton.Enable = 'off';
            % disable run button while running
            app.RunButton.Enable = 'off';
            % enable stop button
            app.StopButton.Enable = 'on';
            app.stopped = 0;
                        
            % time per iteration
            magnitude = app.T/4;            
            step = 1;
            while ~isempty(app.c)
                % pause run
                if app.stopped
                    return
                end
                % update wave rays
                for i = (1:1:length(app.c))
                    cur_y = find(app.y <= app.y_pos(i), 1, 'last');
                    cur_x = find(app.x <= app.x_pos(i), 1, 'last');
                    % save values from previous step
                    last_angle = app.wave_angles(i)-app.grad(cur_y, cur_x);
                    last_c = app.c(i);
                    % get current depth
                    depth = -app.h(cur_y, cur_x);
                    if isempty(depth)
                        app.c(i) = nan;
                        continue
                    end
                    % get L with Newton-Raphson
                    [k, e] = newton_raphson(app.T, depth);
                    L = 2*pi/k;
                    % get c with dispersion equation
                    app.c(i) = (L/app.T)/1000; % celerity in km
                    % get theta with Snell's Law
                    arg = (app.c(i)/last_c)*sind(last_angle);
                    if abs(arg) > 1
                        arg = fix(arg);
                    end
                    app.wave_angles(i) = mod(asind(arg) + app.grad(cur_y, cur_x), 360);
                end
                
                % remove grounded wave rays
                grounded = find(isnan(app.c));
                app.x_final = [app.x_final, app.x_pos(grounded)];
                app.y_final = [app.y_final, app.y_pos(grounded)];
                app.removeWaveRaysHelper(grounded);
                
                % update wave ray positions
                x_dist = (magnitude*app.c).*sind(app.wave_angles);
                y_dist = (magnitude*app.c).*cosd(app.wave_angles);
                
                % fix rays which cross over
                [x_dist, y_dist] = app.mergeRays(x_dist, y_dist);
                x_new = app.x_pos + x_dist;
                y_new = app.y_pos - y_dist;
                
                % remove out of bounds rays
                out_of_bounds = find(x_new > app.x(end) | x_new < app.x(1));
                app.removeWaveRaysHelper(out_of_bounds);
                x_new(out_of_bounds) = [];
                y_new(out_of_bounds) = [];
                
                % update plot
                ax = app.coastAxes;
                % plot wave rays
                for i = (1:1:length(app.x_pos))
                    plot(ax, [app.x_pos(i) x_new(i)], [app.y_pos(i) y_new(i)], 'o-r', 'markerfacecolor', 'r',...
                        'markersize', 2);
                end
                grounded = find(isnan(y_new));
                app.x_final = [app.x_final, app.x_pos(grounded)];
                app.y_final = [app.y_final, app.y_pos(grounded)];
                app.x_pos = x_new;
                app.y_pos = y_new;
                % plot new wave crest every minute
                if mod(step, floor(60/magnitude)) == 0
                    plot(ax, app.x_pos, app.y_pos, 'o-k', 'markerfacecolor', 'r', 'markersize', 4);
                end
                
                % next step
                step = step +1;
                pause(app.wait_time);                
            end
            
            % plot wave ray distances
            app.x_final(isnan(app.x_final)) = [];
            app.y_final(isnan(app.y_final)) = [];
            [app.x_final, sorted_order] = sort(app.x_final);
            app.y_final = app.y_final(sorted_order);
            dists = sqrt((app.x_final(2:end) - app.x_final(1:end-1)).^2 + ...
                (app.y_final(2:end) - app.y_final(1:end-1)).^2);
            % normalize by initital distance between rays
            init_dist = (length(app.x)*app.x_step)/app.num_rays;
            
            dists = dists./init_dist;
            % create own color map
            colors = redblue(100);
            for col = 1:length(app.x)
                if app.x(col) < app.x_final(1) || app.x(col) > app.x_final(end)
                    continue;
                end
                d = dists(find(app.x_final > app.x(col), 1) - 1);
                color_index = 101 - min(floor(d*50)+1, 100);
                plot(app.coastAxes, [col*app.x_step, col*app.x_step], [(max(0, (app.shoreline(col) - 5))*app.y_step),...
                    (app.shoreline(col)-1)*app.y_step],'-','color', colors(color_index, :),'linewidth', 2);
            
            end
                        
            % end procedure
            app.InitializeButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.stopped = 1;
        end

        % Value changed function: SpeedKnob
        function SpeedKnobValueChanged(app, event)
            app.wait_time = .01* (10/app.SpeedKnob.Value);
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % re-enable initialize and start button
            app.InitializeButton.Enable = 'on';
            app.RunButton.Enable = 'on';
            % disable stop button 
            app.StopButton.Enable = 'off';            
            % stop run
            app.stopped = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.InitializeButton = uibutton(app.ControlsPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.StopButton = uibutton(app.ControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RunButton = uibutton(app.ControlsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SetConditionsPanel = uipanel(app.UIFigure);
            app.SetConditionsPanel.Title = 'Set Conditions';
            app.shorelineshapeDropDownLabel = uilabel(app.SetConditionsPanel);
            app.shorelineshapeDropDownLabel.Text = 'shoreline shape';
            app.shorelineshapeDropDown = uidropdown(app.SetConditionsPanel);
            app.shorelineshapeDropDown.Items = {'linear', 'headland', 'bay'};
            app.shorelineshapeDropDown.Value = 'linear';
            app.waveperiodsecSpinnerLabel = uilabel(app.SetConditionsPanel);
            app.waveperiodsecSpinnerLabel.Text = 'wave period (sec)';
            app.waveperiodSpinner = uispinner(app.SetConditionsPanel);
            app.waveperiodSpinner.Limits = [5 20];
            app.waveperiodSpinner.Value = 10;
            app.contoursButtonGroup = uibuttongroup(app.SetConditionsPanel);
            app.contoursButtonGroup.Title = 'contours';
            app.parallelButton = uiradiobutton(app.contoursButtonGroup);
            app.parallelButton.Text = 'parallel';
            app.parallelButton.Value = true;
            app.shoalsButton = uiradiobutton(app.contoursButtonGroup);
            app.shoalsButton.Text = 'shoals';
            app.borrowpitButton = uiradiobutton(app.contoursButtonGroup);
            app.borrowpitButton.Text = 'borrow pit';
            app.waveangledegLabel = uilabel(app.SetConditionsPanel);
            app.waveangledegLabel.Text = 'wave angle (deg)';
            app.waveangleSpinner = uispinner(app.SetConditionsPanel);
            app.waveangleSpinner.Step = 10;
            app.waveangleSpinner.Limits = [-90 90];
            app.WaveRefractionLabel = uilabel(app.UIFigure);
            app.WaveRefractionLabel.Text = 'Wave Refraction';
            app.SpeedKnobLabel = uilabel(app.UIFigure);
            app.SpeedKnobLabel.Text = 'Speed';
            app.SpeedKnob = uiknob(app.UIFigure, 'continuous');
            app.SpeedKnob.Limits = [1 10];
            app.SpeedKnob.ValueChangedFcn = createCallbackFcn(app, @SpeedKnobValueChanged, true);
            app.SpeedKnob.MinorTicks = [];
            app.SpeedKnob.Value = 4;
            app.coastAxes = uiaxes(app.UIFigure);
            xlabel(app.coastAxes, 'long-shore pos. (km)')
            ylabel(app.coastAxes, 'cross-shore pos. (km)')
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Icon = 'help_icon.png';
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Refraction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end