classdef SedTrans < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ControlsPanel              matlab.ui.container.Panel
        StopButton                 matlab.ui.control.Button
        RunButton                  matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        HelpButton                 matlab.ui.control.Button
        SetConditionsPanel         matlab.ui.container.Panel
        breakingwaveheightmLabel   matlab.ui.control.Label
        waveHeightSpinner          matlab.ui.control.Spinner
        breakingwaveangledegLabel  matlab.ui.control.Label
        waveAngleGauge             matlab.ui.control.SemicircularGauge
        wave_up                    matlab.ui.control.Button
        wave_down                  matlab.ui.control.Button
        WaveInducedSedimentTransportLabel  matlab.ui.control.Label
        coastAxes                  matlab.ui.control.UIAxes
        paramsAxes                 matlab.ui.control.UIAxes
        fluxAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        breaking_wave_angle              % degrees
        breaking_wave_height             % m
        slope = 0.01                     % shoreface slope
                
        x                                % longshore domain
        y                                % cross-shore domain
        shoreline                        % y pos of shoreline
        angles                           % local shoreline angles
        h                                % bathy grid
        
        save_handles        
        sed_flux
        energy
        spread
        max_vals = 200;
        i
        
        dir
        stopped                          % signifies app is stopped
    end

    methods (Access = private)
    
        function render_plots(app)            
            ax = app.paramsAxes;
            
            % plot longshore energy density
            yyaxis(ax, 'left');
            if length(app.energy) > app.max_vals
                app.energy = app.energy(2:end);
            end
            pad = padarray(app.energy, [0, app.max_vals-length(app.energy)], nan, 'post');
            plot(ax, app.i, pad, '*');
            
            % plot energy spread
            yyaxis(ax, 'right');
            if length(app.spread) > app.max_vals
                app.spread = app.spread(2:end);
            end
            pad = padarray(app.spread, [0, app.max_vals-length(app.spread)], nan, 'post');
            plot(ax, app.i, pad, '*');
            
            % plot sed flux
            ax = app.fluxAxes;
            if length(app.sed_flux) > app.max_vals
                app.sed_flux = app.sed_flux(2:end);
            end
            pad = padarray(app.sed_flux, [0, app.max_vals-length(app.sed_flux)], nan, 'post');
            plot(ax, app.i, pad, 'k*');
        end
        
        function render_wave(app)
            % initialize wave vector
            break_depth = app.breaking_wave_height/app.slope + app.shoreline;
            alpha = deg2rad(app.breaking_wave_angle);
            if sin(alpha) > 0 % anchor to left side
                left_y = break_depth - ((break_depth-app.shoreline)*sin(alpha));
                right_y = left_y + (tan(alpha)*app.x(end));
            else % anchor to right side
                right_y = break_depth + ((break_depth-app.shoreline)*sin(alpha));
                left_y = right_y - (tan(alpha)*app.x(end));
            end
                        
            % plot wave crest
            delete(app.save_handles);
            app.save_handles = [];
            ax = app.coastAxes;
            app.save_handles(end+1) = plot(app.coastAxes, [0, app.x(end)], [left_y, right_y], '-r', 'linewidth', 2);
            ylim(ax, [0 app.y(end)]);
            xlim(ax, [0, app.x(end)]);
            
            % plot wave rays
            w = 15;
            if alpha == 0
                break_x = [app.x(end)/2-w, app.x(end)/2+w];
                break_y = [break_depth, break_depth];
            else
                center_x = (break_depth-left_y)/tan(alpha);
                break_x = [center_x-(w*cos(alpha)), center_x+(w*cos(alpha))];
                break_y = [break_depth-(w*sin(alpha)), break_depth+(w*sin(alpha))];
            end
                        
            shore_x = break_x + ((break_y-app.shoreline)*tan(alpha));
            app.save_handles(end+1) = plot(ax, [break_x(1), shore_x(1)], [break_y(1), app.shoreline],...
                'r-.', 'linewidth', 2);
            app.save_handles(end+1) = plot(ax, [break_x(2), shore_x(2)], [break_y(2), app.shoreline],...
                'r-.', 'linewidth', 2);
            
            % plot longshore energy component
            E = ((1/8)*(1027)*(9.82)*(app.breaking_wave_height^2));
            E_longshore = E*sin(alpha);
            app.energy(end+1) = abs(E_longshore);
            E_cross = E*cos(alpha);
            if E_longshore > 0
                app.save_handles(end+1) = plot(ax, [break_x(2), break_x(2) + E_longshore/10], [break_y(2), break_y(2)], ...
                'w->', 'linewidth', 1.5, 'markersize', 5, 'markerfacecolor', 'w');
                app.save_handles(end+1) = plot(ax, [break_x(2), break_x(2)], [break_y(2), break_y(2)-E_cross/22], ...
                'w-v', 'linewidth', 1.5, 'markersize', 5, 'markerfacecolor', 'w');
            app.save_handles(end+1) = text(ax, break_x(1) + E_longshore/10, break_y(1), ....
                '$\vec{E}_y = Esin(\alpha)\vec{n}_y$', 'interpreter', 'latex', 'fontsize', 16, 'color', 'w');
            else
                app.save_handles(end+1) = plot(ax, [break_x(1), break_x(1) + E_longshore/10], [break_y(1), break_y(1)], ...
                'w-<', 'linewidth', 1.5, 'markersize', 5, 'markerfacecolor', 'w');
                app.save_handles(end+1) = plot(ax, [break_x(2), break_x(2)], [break_y(2), break_y(2)-E_cross/22], ...
                'w-v', 'linewidth', 1.5, 'markersize', 5, 'markerfacecolor', 'w');
            app.save_handles(end+1) = text(ax, break_x(1) + E_longshore/10, break_y(2), ....
                '$\vec{E}_y = Esin(\alpha)\vec{n}_y$', 'interpreter', 'latex', 'fontsize', 16, 'color', 'w');
            end
            app.save_handles(end+1) = text(ax, break_x(1)-10, break_y(1) - E_cross/22, ....
                '$\vec{E}_x = Ecos(\alpha)\vec{n}_x$', 'interpreter', 'latex', 'fontsize', 16, 'color', 'w', 'Rotation', 90);
            
            % plot width
            sign = -1;
            if sin(alpha) < 0
                sign = 1;
            end
            app.save_handles(end+1) = plot(ax, [break_x(1), break_x(2)], [break_y(1), break_y(2)], ...
            'b-+', 'linewidth', 1.5, 'markersize', 12, 'markerfacecolor', 'b');
            app.save_handles(end+1) = text(ax, break_x(1)+ (sign*25), break_y(1)+15, '$W_{break}$', 'interpreter', 'latex', ...
                'fontsize', 16, 'color', 'w', 'rotation', app.breaking_wave_angle);
            
            app.save_handles(end+1) = plot(ax, [shore_x(1), shore_x(2)], [app.shoreline, app.shoreline], ...
            'b-+', 'linewidth', 1.5, 'markersize', 12, 'markerfacecolor', 'b');
            app.save_handles(end+1) = text(ax, shore_x(1)+1, 3, '$W_{shore}$', 'interpreter', 'latex', ...
                'fontsize', 16, 'color', 'b');
            app.spread(end+1) = ...
                (sqrt((break_x(2)-break_x(1))^2 + (break_y(2) - break_y(1))^2))/(shore_x(2) - shore_x(1));
            
            app.sed_flux(end+1) = abs(0.4*(app.breaking_wave_height^(5/2))*cos(alpha)*sin(alpha));
        end
        
        function render_bathy(app)                        
            % initialize coastline
            app.shoreline = 20;            
            
            % build bathy grid
            for r = 1:length(app.y)
                for col = 1:length(app.x)
                    if r <= app.shoreline/2
                        app.h(r, col) = nan; % land
                    else
                        app.h(r, col) = -(2 * app.slope) * (r - app.shoreline/2); % depth (m)
                    end
                end
            end

            
            % plot bathy
            ax = app.coastAxes;
            cla(ax);        
            set(ax, 'color', 'yellow');
            contourf(ax, app.x, app.y, app.h, 20);
            hold(ax, 'on');
            shore = (app.shoreline) * ones(size(app.x));
            plot(ax, app.x, shore, 'k-', 'linewidth', 2);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.i = (1:1:app.max_vals);   
            app.ResetButtonPushed();
            set(app.ResetButton, 'Tooltip','Volumetric alongshore sediment flux rate');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % init user inputs
            app.breaking_wave_angle = app.waveAngleGauge.Value;
            app.breaking_wave_height = app.waveHeightSpinner.Value;      
            
            % initialize global values
            app.x = (0:5:800);
            app.y = (0:2:400);
            app.h = zeros(length(app.y), length(app.x));
            app.save_handles = [];
            app.sed_flux = [];
            app.energy = [];
            app.spread = [];
            app.dir = 1;
            
            % set up shoreline axes
            ax = app.coastAxes;
            cla(ax);
            set(ax, 'XAxisLocation', 'top');
            colormap(ax, 'winter');
            ylim(ax, [0 app.y(end)]);
            xlim(ax, [0, app.x(end)]);
            ylabel(ax, 'cross-shore pos. (m)');   
            xlabel(ax, 'long-shore pos. (m)');
            axis(ax, 'equal');
            
            % set up double axes
            ax = app.paramsAxes;
            cla(ax);
            xticks(ax, []);
            xticklabels(ax, []);
            xlim(ax, [1, app.max_vals]);
            yyaxis(ax, 'left')
            ytickangle(ax, 90);
            ylabel(ax, '|E_{y}| (J/m^2)');
            ylim(ax, [0, 20000]);
            yyaxis(ax, 'right');
            ytickangle(ax, 90);
            ylabel(ax, 'w_{break}/w_{shore}');
            ylim(ax, [0 1]);
            
            % set up flux axes
            ax = app.fluxAxes;
            cla(ax);
            xticks(ax, []);
            xticklabels(ax, []);
            xlim(ax, [1, app.max_vals]);
            ylabel(ax, 'sed. flux (m^3/s)');
            ytickangle(ax, 90);
            ylim(ax, [0, 8]);
            
            app.render_bathy();
            app.render_wave();
            app.render_plots();
            
            % initialize run settings
            app.stopped = 1;
            app.RunButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.stopped = 1;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.StopButton.Enable = 'on';
            app.RunButton.Enable = 'off';
            app.ResetButton.Enable = 'off';
            app.wave_up.Enable = 'off';
            app.wave_down.Enable = 'off';     
            app.stopped = 0;
            
            while ~app.stopped
                app.breaking_wave_angle = app.breaking_wave_angle + (app.dir*5);
                app.waveAngleGauge.Value = app.breaking_wave_angle;
                
                if abs(app.breaking_wave_angle) == 90
                    app.dir = app.dir*-1;
                end
                
                app.render_wave();
                app.render_plots();
                pause(0.2);
            end
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopButton.Enable = 'off';
            app.RunButton.Enable = 'on';
            app.ResetButton.Enable = 'on';
            app.wave_up.Enable = 'on';
            app.wave_down.Enable = 'on';
            app.stopped = 1;
        end

        % Value changed function: waveHeightSpinner
        function waveHeightSpinnerValueChanged(app, event)
            app.breaking_wave_height = app.waveHeightSpinner.Value;
            app.render_plots();
        end

        % Button pushed function: wave_down
        function wave_downButtonPushed(app, event)
            app.wave_up.Enable = 'on';
            app.breaking_wave_angle = app.breaking_wave_angle - 5;
            app.waveAngleGauge.Value = app.breaking_wave_angle;
            if app.breaking_wave_angle == -90
                app.wave_down.Enable = 'off';
            end
            app.render_wave();
            app.render_plots();
        end

        % Button pushed function: wave_up
        function wave_upButtonPushed(app, event)
            app.wave_down.Enable = 'on';
            app.breaking_wave_angle = app.breaking_wave_angle + 5;
            app.waveAngleGauge.Value = app.breaking_wave_angle;
            if app.breaking_wave_angle == 90
                app.wave_up.Enable = 'off';
            end
            app.render_wave();
            app.render_plots();
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            sedTransHelpApp
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.StopButton = uibutton(app.ControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RunButton = uibutton(app.ControlsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ResetButton = uibutton(app.ControlsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.HelpButton = uibutton(app.ControlsPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Icon = 'help_icon.png';
            app.HelpButton.Text = 'Help';
            app.SetConditionsPanel = uipanel(app.UIFigure);
            app.SetConditionsPanel.Title = 'Set Conditions';
            app.breakingwaveheightmLabel = uilabel(app.SetConditionsPanel);
            app.breakingwaveheightmLabel.Text = 'breaking wave height (m)';
            app.waveHeightSpinner = uispinner(app.SetConditionsPanel);
            app.waveHeightSpinner.ValueChangedFcn = createCallbackFcn(app, @waveHeightSpinnerValueChanged, true);
            app.waveHeightSpinner.Limits = [1 4];
            app.waveHeightSpinner.Value = 2;
            app.breakingwaveangledegLabel = uilabel(app.SetConditionsPanel);
            app.breakingwaveangledegLabel.Text = 'breaking wave angle (deg)';
            app.waveAngleGauge = uigauge(app.SetConditionsPanel, 'semicircular');
            app.waveAngleGauge.Limits = [-90 90];
            app.waveAngleGauge.ScaleDirection = 'counterclockwise';
            app.waveAngleGauge.Value = 45;
            app.wave_up = uibutton(app.SetConditionsPanel, 'push');
            app.wave_up.ButtonPushedFcn = createCallbackFcn(app, @wave_upButtonPushed, true);
            app.wave_up.Text = '+';
            app.wave_down = uibutton(app.SetConditionsPanel, 'push');
            app.wave_down.ButtonPushedFcn = createCallbackFcn(app, @wave_downButtonPushed, true);
            app.wave_down.Text = '-';
            app.WaveInducedSedimentTransportLabel = uilabel(app.UIFigure);
            app.WaveInducedSedimentTransportLabel.Text = 'Wave-Induced Sediment Transport';
            app.coastAxes = uiaxes(app.UIFigure);
            ylabel(app.coastAxes, 'cross-shore pos. (m)')
            app.paramsAxes = uiaxes(app.UIFigure);
            app.fluxAxes = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = SedTrans

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end